classdef write_wecsim_input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup_3                      matlab.ui.container.TabGroup
        Const1Tab                       matlab.ui.container.Tab
        Const1LocationEditField         matlab.ui.control.EditField
        Const1LocationEditFieldLabel    matlab.ui.control.Label
        Const2Tab                       matlab.ui.container.Tab
        Const2LocationEditField         matlab.ui.control.EditField
        Const2LocationEditFieldLabel    matlab.ui.control.Label
        Const3Tab                       matlab.ui.container.Tab
        Const3LocationEditField         matlab.ui.control.EditField
        Const3LocationEditFieldLabel    matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        PTO1Tab                         matlab.ui.container.Tab
        PTO1DampingEditField            matlab.ui.control.NumericEditField
        PTO1DampingEditFieldLabel       matlab.ui.control.Label
        PTO1LocationEditField           matlab.ui.control.EditField
        PTO1LocationEditFieldLabel      matlab.ui.control.Label
        PTO1StiffnessEditField          matlab.ui.control.NumericEditField
        PTO1StiffnessEditFieldLabel     matlab.ui.control.Label
        PTO2Tab                         matlab.ui.container.Tab
        PTO2DampingEditField            matlab.ui.control.NumericEditField
        PTO2DampingEditFieldLabel       matlab.ui.control.Label
        PTO2LocationEditField           matlab.ui.control.EditField
        PTO2LocationLabel               matlab.ui.control.Label
        PTO2StiffnessEditField          matlab.ui.control.NumericEditField
        PTO2StiffnessLabel              matlab.ui.control.Label
        PTO3Tab                         matlab.ui.container.Tab
        PTO3DampingEditField            matlab.ui.control.NumericEditField
        PTO3DampingEditFieldLabel       matlab.ui.control.Label
        PTO3LocationEditField           matlab.ui.control.EditField
        PTO3LocationEditFieldLabel      matlab.ui.control.Label
        PTO3StiffnessEditField          matlab.ui.control.NumericEditField
        PTO3StiffnessLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Body1Tab                        matlab.ui.container.Tab
        Body1MomentofInertiaIxxIyyIzzEditField  matlab.ui.control.EditField
        Body1MomentofInertiaIxxIyyIzzEditFieldLabel  matlab.ui.control.Label
        Body1MassEditField              matlab.ui.control.NumericEditField
        Body1MassEditFieldLabel         matlab.ui.control.Label
        Body1GeometryFilenameEditField  matlab.ui.control.EditField
        Body1GeometryFilenameEditFieldLabel  matlab.ui.control.Label
        Body2Tab                        matlab.ui.container.Tab
        Body2GeometryFilenameEditField  matlab.ui.control.EditField
        Body2GeometryFilenameEditFieldLabel  matlab.ui.control.Label
        Body2MassEditField              matlab.ui.control.NumericEditField
        Body2MassEditFieldLabel         matlab.ui.control.Label
        Body2MomentofInertiaIxxIyyIzzEditField  matlab.ui.control.EditField
        Body2MomentofInertiaIxxIyyIzzEditFieldLabel  matlab.ui.control.Label
        Body3Tab                        matlab.ui.container.Tab
        Body3GeometryFilenameEditField  matlab.ui.control.EditField
        Body3GeometryFilenameEditFieldLabel  matlab.ui.control.Label
        Body3MassEditField              matlab.ui.control.NumericEditField
        Body3MassEditFieldLabel         matlab.ui.control.Label
        Body3MomentofInertiaIxxIyyIzzEditField  matlab.ui.control.EditField
        Body3MomentofInertiaIxxIyyIzzEditFieldLabel  matlab.ui.control.Label
        SpectrumTypePMJSEditField       matlab.ui.control.EditField
        SpectrumTypePMJSEditFieldLabel  matlab.ui.control.Label
        WavePeriodsEditField            matlab.ui.control.NumericEditField
        WavePeriodsEditFieldLabel       matlab.ui.control.Label
        WaveHeightmEditField            matlab.ui.control.NumericEditField
        WaveHeightmEditFieldLabel       matlab.ui.control.Label
        SimulationParametersPanel       matlab.ui.container.Panel
        NoofConstraintsEditField        matlab.ui.control.NumericEditField
        NoofConstraintsEditFieldLabel   matlab.ui.control.Label
        NoofPTOsEditField               matlab.ui.control.NumericEditField
        NoofPTOsEditFieldLabel          matlab.ui.control.Label
        NoofBodiesEditField             matlab.ui.control.NumericEditField
        NoofBodiesEditFieldLabel        matlab.ui.control.Label
        h5FilenameEditField             matlab.ui.control.EditField
        h5FilenameEditFieldLabel        matlab.ui.control.Label
        ConvolutionEndTimeEditField     matlab.ui.control.NumericEditField
        ConvolutionEndTimeEditFieldLabel  matlab.ui.control.Label
        dtEditField                     matlab.ui.control.NumericEditField
        dtEditFieldLabel                matlab.ui.control.Label
        SimulinkFilenameEditField       matlab.ui.control.EditField
        SimscapeExplorerButtonGroup     matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        OnButton                        matlab.ui.control.RadioButton
        SimulinkFilnenameEditFieldLabel  matlab.ui.control.Label
        GenerateWECSimInputFileButton   matlab.ui.control.Button
        WaveInformationPanel            matlab.ui.container.Panel
        WaveTypeDropDown                matlab.ui.control.DropDown
        WaveTypeDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        simu = struct();% Description
        dt   = 0.01;
        cicEndTime = 30
        wavetype   = 'noWaveCIC'
        waveheight = 0;
        waveperiod = 0;
        spectrumtype
        h5
        indices
        newData
        body1geometryfile
        body1mass
        body1MOI
        body2geometryfile
        body2mass
        body2MOI
        body3geometryfile
        body3mass
        body3MOI
        pto1C
        pto1K
        pto1loc
        pto2C
        pto2K
        pto2loc
        pto3C
        pto3K
        pto3loc
        const1loc
        const2loc
        const3loc
        bodynum
        constnum
        ptonum
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateWECSimInputFileButton
        function GenerateWECSimInputFileButtonPushed(app, event)
filename = 'wecsimtest';

fid=fopen(strcat(filename,'.m'),'w');
fprintf(fid,'%%%% Simulation Data \n');
fprintf(fid,'simu = simulationClass();               %%%% Initialize Simulation Class \n');
fprintf(fid,"simu.simMechanicsFile = '%s';    %%%% Specify Simulink Model File\n",app.simu.simMechanicsFile);
fprintf(fid,"simu.mode = 'normal';                   %%%% Specify Simulation Mode ('normal','accelerator','rapid-accelerator')\n");
fprintf(fid,"simu.explorer = '%s';                   %%%% Turn SimMechanics Explorer (on/off)\n",app.simu.explorer);
fprintf(fid,'simu.startTime = 0;                     %%%% Simulation Start Time [s]\n');
fprintf(fid,'simu.rampTime = 100;                    %%%% Wave Ramp Time [s]   \n');
fprintf(fid,'simu.endTime = 400;                     %%%% Simulation End Time [s]     \n');   
fprintf(fid,"simu.solver = 'ode4';                   %%%% simu.solver = 'ode4' for fixed step & simu.solver = 'ode45' for variable step \n");
fprintf(fid,'simu.dt = %d;                          %%%% Simulation Time-Step [s]\n',app.dt);
fprintf(fid,'simu.cicEndTime = %d;                   %%%% Specify CI Time [s]\n',app.cicEndTime);

fprintf(fid,'%%%%%%%% Wave Information\n');
fprintf(fid,'%%%% %%%% noWaveCIC, no waves with radiation CIC  \n');
fprintf(fid,"%% waves = waveClass('noWaveCIC');       %% Initialize Wave Class and Specify Type  \n");
fprintf(fid,'%% %% Regular Waves \n');
fprintf(fid,"%% waves = waveClass('regular');           %% Initialize Wave Class and Specify Type      \n");                           
fprintf(fid,'%% waves.height = 2.5;                     %% Wave Height [m]\n');
fprintf(fid,'%% waves.period = 8;                       %% Wave Period [s]\n');
fprintf(fid,"%% %% Irregular Waves with imported spectrum\n");
fprintf(fid,"%% waves = waveClass('spectrumImport');      %% Create the Wave Variable and Specify Type\n");
fprintf(fid,"%% waves.spectrumFile = 'spectrumData.mat';  %% Name of User-Defined Spectrum File [:,2] = [f, Sf]\n");
fprintf(fid,'%% %% Waves with imported wave elevation time-history  \n');
fprintf(fid,"%% waves = waveClass('elevationImport');          %% Create the Wave Variable and Specify Type\n");
fprintf(fid,"%% waves.elevationFile = 'elevationData.mat';     %% Name of User-Defined Time-Series File [:,2] = [time, eta]\n");

fprintf(fid,'%% Irregular Waves using PM Spectrum with Directionality \n');
fprintf(fid,"waves = waveClass('%s');         %% Initialize Wave Class and Specify Type\n",app.wavetype);
fprintf(fid,'waves.height = %d;                     %% Significant Wave Height [m]\n',app.waveheight);
fprintf(fid,'waves.period = %d;                       %% Peak Period [s]\n',app.waveperiod);
fprintf(fid,"waves.spectrumType = '%s';              %% Specify Spectrum Type\n",app.spectrumtype);
fprintf(fid,'waves.direction = [0,30,90];            %% Wave Directionality [deg]\n');
fprintf(fid,'waves.spread = [0.1,0.2,0.7];           %% Wave Directional Spreading [%%}\n');

fprintf(fid,'%%%% Body Data\n');

for i = 1:app.bodynum
fprintf(fid,"body(%d) = bodyClass('hydroData/%s.h5');      %% Initialize bodyClass for Flap\n",i,app.h5);
fprintf(fid,"body(%d).geometryFile = 'geometry/%s.stl';     %% Geometry File\n",       i,eval(sprintf('app.body%dgeometryfile',i)));
fprintf(fid,'body(%d).mass = %f;                          %% User-Defined mass [kg]\n',i,eval(sprintf('app.body%dmass',i)));
fprintf(fid,'body(%d).inertia = %s;       %% Moment of Inertia [kg-m^2]\n',            i,eval(sprintf('app.body%dMOI' ,i)));
end


fprintf(fid,'%%%% PTO and Constraint Parameters\n');
for i = 1:app.constnum
fprintf(fid,"constraint(%d)= constraintClass('Constraint%d');  %% Initialize ConstraintClass for Constraint%d\n",i,i,i);
fprintf(fid,'constraint(%d).location = %s;             %% Constraint Location [m]\n',i,eval(sprintf('app.const%dloc',i)));
end

for i = 1:app.ptonum
fprintf(fid,"pto(%d) = ptoClass('PTO%d');             %% Initialize ptoClass for PTO%d\n"                              ,i,i,i);
fprintf(fid,'pto(%d).stiffness = %f;                  %% PTO Stiffness Coeff [Nm/rad]\n',       i,eval(sprintf('app.pto%dK',i)));
fprintf(fid,'pto(%d).damping   = %f;                  %% PTO Damping Coeff [Nsm/rad]\n',        i,eval(sprintf('app.pto%dC',i)));
fprintf(fid,'pto(%d).location  = %s;                  %% PTO Location [m]\n',                   i,eval(sprintf('app.pto%dloc',i)));
end


fclose(fid);
        end

        % Value changed function: SimulinkFilenameEditField
        function SimulinkFilenameEditFieldValueChanged(app, event)
            app.simu.simMechanicsFile = app.SimulinkFilenameEditField.Value;
            
        end

        % Callback function: SimscapeExplorerButtonGroup, UIFigure
        function SimscapeExplorerButtonGroupButtonDown(app, event)
            
            if app.OffButton.Value == 1
            app.simu.explorer =  app.OffButton.Text;
            elseif app.OnButton.Value == 1
            app.simu.explorer = app.OnButton.Text;
            end

        end

        % Value changed function: dtEditField
        function dtEditFieldValueChanged(app, event)
            app.dt = app.dtEditField.Value;
            
        end

        % Value changed function: ConvolutionEndTimeEditField
        function ConvolutionEndTimeEditFieldValueChanged(app, event)
            app.cicEndTime = app.ConvolutionEndTimeEditField.Value;
            
        end

        % Callback function
        function WaveTypeDropDownOpening(app, event)
            app.wavetype = app.WaveTypeDropDownOpening.Value;
        end

        % Value changed function: WaveHeightmEditField
        function WaveHeightmEditFieldValueChanged(app, event)
            app.waveheight = app.WaveHeightmEditField.Value;
            
        end

        % Value changed function: WavePeriodsEditField
        function WavePeriodsEditFieldValueChanged(app, event)
            app.waveperiod = app.WavePeriodsEditField.Value;
            
        end

        % Value changed function: SpectrumTypePMJSEditField
        function SpectrumTypePMJSEditFieldValueChanged(app, event)
            app.spectrumtype = app.SpectrumTypePMJSEditField.Value;
            
        end

        % Value changed function: h5FilenameEditField
        function h5FilenameEditFieldValueChanged(app, event)
            app.h5 = app.h5FilenameEditField.Value;
            
        end

        % Value changed function: Body1MassEditField
        function Body1MassEditFieldValueChanged(app, event)
            app.body1mass = app.Body1MassEditField.Value;
            
        end

        % Value changed function: Body1MomentofInertiaIxxIyyIzzEditField
        function Body1MomentofInertiaIxxIyyIzzEditFieldValueChanged(app, event)
            app.body1MOI = app.Body1MomentofInertiaIxxIyyIzzEditField.Value;
            
        end

        % Value changed function: Body1GeometryFilenameEditField
        function Body1GeometryFilenameEditFieldValueChanged(app, event)
            app.body1geometryfile = app.Body1GeometryFilenameEditField.Value;
            
        end

        % Value changed function: Body2MassEditField
        function Body2MassEditFieldValueChanged(app, event)
            app.body2mass = app.Body2MassEditField.Value;
            
        end

        % Value changed function: Body2MomentofInertiaIxxIyyIzzEditField
        function Body2MomentofInertiaIxxIyyIzzEditFieldValueChanged(app, event)
            app.body2MOI  = app.Body2MomentofInertiaIxxIyyIzzEditField.Value;
            
        end

        % Value changed function: Body2GeometryFilenameEditField
        function Body2GeometryFilenameEditFieldValueChanged(app, event)
            app.body2geometryfile = app.Body2GeometryFilenameEditField.Value;
            
        end

        % Value changed function: Body3MassEditField
        function Body3MassEditFieldValueChanged(app, event)
            app.body3mass = app.Body3MassEditField.Value;
            
        end

        % Value changed function: Body3MomentofInertiaIxxIyyIzzEditField
        function Body3MomentofInertiaIxxIyyIzzEditFieldValueChanged(app, event)
            app.body3MOI = app.Body3MomentofInertiaIxxIyyIzzEditField.Value;
            
        end

        % Value changed function: Body3GeometryFilenameEditField
        function Body3GeometryFilenameEditFieldValueChanged(app, event)
            app.body3geometryfile = app.Body3GeometryFilenameEditField.Value;
            
        end

        % Value changed function: Const1LocationEditField
        function Const1LocationEditFieldValueChanged(app, event)
            app.const1loc = app.Const1LocationEditField.Value;
            
        end

        % Value changed function: Const2LocationEditField
        function Const2LocationEditFieldValueChanged(app, event)
            app.const2loc  = app.Const2LocationEditField.Value;
            
        end

        % Value changed function: Const3LocationEditField
        function Const3LocationEditFieldValueChanged(app, event)
            app.const3loc = app.Const3LocationEditField.Value;
            
        end

        % Value changed function: PTO1DampingEditField
        function PTO1DampingEditFieldValueChanged(app, event)
            app.pto1C = app.PTO1DampingEditField.Value;
            
        end

        % Value changed function: PTO1StiffnessEditField
        function PTO1StiffnessEditFieldValueChanged(app, event)
            app.pto1K = app.PTO1StiffnessEditField.Value;
            
        end

        % Value changed function: PTO1LocationEditField
        function PTO1LocationEditFieldValueChanged(app, event)
            app.pto1loc = app.PTO1LocationEditField.Value;
            
        end

        % Value changed function: PTO2DampingEditField
        function PTO2DampingEditFieldValueChanged(app, event)
            app.pto2C = app.PTO2DampingEditField.Value;
            
        end

        % Value changed function: PTO2StiffnessEditField
        function PTO2StiffnessEditFieldValueChanged(app, event)
            app.pto2K = app.PTO2StiffnessEditField.Value;
            
        end

        % Value changed function: PTO2LocationEditField
        function PTO2LocationEditFieldValueChanged(app, event)
            app.pto2loc = app.PTO2LocationEditField.Value;
            
        end

        % Value changed function: PTO3DampingEditField
        function PTO3DampingEditFieldValueChanged(app, event)
            app.pto3C = app.PTO3DampingEditField.Value;
            
        end

        % Value changed function: PTO3StiffnessEditField
        function PTO3StiffnessEditFieldValueChanged(app, event)
            app.pto3K = app.PTO3StiffnessEditField.Value;
            
        end

        % Value changed function: PTO3LocationEditField
        function PTO3LocationEditFieldValueChanged(app, event)
            app.pto3loc = app.PTO3LocationEditField.Value;
            
        end

        % Value changed function: WaveTypeDropDown
        function WaveTypeDropDownValueChanged(app, event)
            app.wavetype = app.WaveTypeDropDown.Value;
            
        end

        % Value changed function: NoofBodiesEditField
        function NoofBodiesEditFieldValueChanged(app, event)
            app.bodynum= app.NoofBodiesEditField.Value;
            
        end

        % Value changed function: NoofConstraintsEditField
        function NoofConstraintsEditFieldValueChanged(app, event)
            app.constnum = app.NoofConstraintsEditField.Value;
            
        end

        % Value changed function: NoofPTOsEditField
        function NoofPTOsEditFieldValueChanged(app, event)
            app.ptonum = app.NoofPTOsEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @SimscapeExplorerButtonGroupButtonDown, true);
            app.WaveInformationPanel = uipanel(app.UIFigure);
            app.WaveInformationPanel.Title = 'Wave Information';
            app.WaveTypeDropDownLabel = uilabel(app.WaveInformationPanel);
            app.WaveTypeDropDownLabel.Text = 'Wave Type';
            app.WaveTypeDropDown = uidropdown(app.WaveInformationPanel);
            app.WaveTypeDropDown.Items = {'noWaveCIC', 'regular', 'iiregular'};
            app.WaveTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveTypeDropDownValueChanged, true);
            app.WaveTypeDropDown.Value = 'noWaveCIC';
            app.GenerateWECSimInputFileButton = uibutton(app.UIFigure, 'push');
            app.GenerateWECSimInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWECSimInputFileButtonPushed, true);
            app.GenerateWECSimInputFileButton.Text = 'Generate WEC-Sim Input File';
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.SimulinkFilnenameEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.SimulinkFilnenameEditFieldLabel.Text = 'Simulink Filnename';
            app.SimscapeExplorerButtonGroup = uibuttongroup(app.SimulationParametersPanel);
            app.SimscapeExplorerButtonGroup.Title = 'Simscape Explorer';
            app.SimscapeExplorerButtonGroup.ButtonDownFcn = createCallbackFcn(app, @SimscapeExplorerButtonGroupButtonDown, true);
            app.OnButton = uiradiobutton(app.SimscapeExplorerButtonGroup);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.OffButton = uiradiobutton(app.SimscapeExplorerButtonGroup);
            app.OffButton.Text = 'Off';
            app.SimulinkFilenameEditField = uieditfield(app.SimulationParametersPanel, 'text');
            app.SimulinkFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @SimulinkFilenameEditFieldValueChanged, true);
            app.dtEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @dtEditFieldValueChanged, true);
            app.dtEditField.Value = 0.01;
            app.ConvolutionEndTimeEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.ConvolutionEndTimeEditFieldLabel.Text = 'Convolution End Time';
            app.ConvolutionEndTimeEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ConvolutionEndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @ConvolutionEndTimeEditFieldValueChanged, true);
            app.ConvolutionEndTimeEditField.Value = 30;
            app.h5FilenameEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.h5FilenameEditFieldLabel.Text = 'h5 Filename';
            app.h5FilenameEditField = uieditfield(app.SimulationParametersPanel, 'text');
            app.h5FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @h5FilenameEditFieldValueChanged, true);
            app.NoofBodiesEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.NoofBodiesEditFieldLabel.Text = 'No. of Bodies';
            app.NoofBodiesEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.NoofBodiesEditField.ValueChangedFcn = createCallbackFcn(app, @NoofBodiesEditFieldValueChanged, true);
            app.NoofPTOsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.NoofPTOsEditFieldLabel.Text = 'No. of PTOs';
            app.NoofPTOsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.NoofPTOsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofPTOsEditFieldValueChanged, true);
            app.NoofConstraintsEditFieldLabel = uilabel(app.SimulationParametersPanel);
            app.NoofConstraintsEditFieldLabel.Text = 'No. of Constraints';
            app.NoofConstraintsEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.NoofConstraintsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofConstraintsEditFieldValueChanged, true);
            app.WaveHeightmEditFieldLabel = uilabel(app.UIFigure);
            app.WaveHeightmEditFieldLabel.Text = 'Wave Height (m)';
            app.WaveHeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaveHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @WaveHeightmEditFieldValueChanged, true);
            app.WavePeriodsEditFieldLabel = uilabel(app.UIFigure);
            app.WavePeriodsEditFieldLabel.Text = 'Wave Period (s)';
            app.WavePeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavePeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @WavePeriodsEditFieldValueChanged, true);
            app.SpectrumTypePMJSEditFieldLabel = uilabel(app.UIFigure);
            app.SpectrumTypePMJSEditFieldLabel.Text = 'Spectrum Type [''PM'',''JS'']';
            app.SpectrumTypePMJSEditField = uieditfield(app.UIFigure, 'text');
            app.SpectrumTypePMJSEditField.ValueChangedFcn = createCallbackFcn(app, @SpectrumTypePMJSEditFieldValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Body1Tab = uitab(app.TabGroup);
            app.Body1Tab.Title = 'Body 1';
            app.Body1GeometryFilenameEditFieldLabel = uilabel(app.Body1Tab);
            app.Body1GeometryFilenameEditFieldLabel.Text = 'Body 1 Geometry Filename';
            app.Body1GeometryFilenameEditField = uieditfield(app.Body1Tab, 'text');
            app.Body1GeometryFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @Body1GeometryFilenameEditFieldValueChanged, true);
            app.Body1MassEditFieldLabel = uilabel(app.Body1Tab);
            app.Body1MassEditFieldLabel.Text = 'Body 1 Mass';
            app.Body1MassEditField = uieditfield(app.Body1Tab, 'numeric');
            app.Body1MassEditField.Limits = [0 Inf];
            app.Body1MassEditField.ValueChangedFcn = createCallbackFcn(app, @Body1MassEditFieldValueChanged, true);
            app.Body1MomentofInertiaIxxIyyIzzEditFieldLabel = uilabel(app.Body1Tab);
            app.Body1MomentofInertiaIxxIyyIzzEditFieldLabel.Text = 'Body 1 Moment of Inertia [Ixx, Iyy, Izz]';
            app.Body1MomentofInertiaIxxIyyIzzEditField = uieditfield(app.Body1Tab, 'text');
            app.Body1MomentofInertiaIxxIyyIzzEditField.ValueChangedFcn = createCallbackFcn(app, @Body1MomentofInertiaIxxIyyIzzEditFieldValueChanged, true);
            app.Body1MomentofInertiaIxxIyyIzzEditField.Placeholder = '[0, 0, 0]';
            app.Body2Tab = uitab(app.TabGroup);
            app.Body2Tab.Title = 'Body 2';
            app.Body2MomentofInertiaIxxIyyIzzEditFieldLabel = uilabel(app.Body2Tab);
            app.Body2MomentofInertiaIxxIyyIzzEditFieldLabel.Text = 'Body 2 Moment of Inertia [Ixx, Iyy, Izz]';
            app.Body2MomentofInertiaIxxIyyIzzEditField = uieditfield(app.Body2Tab, 'text');
            app.Body2MomentofInertiaIxxIyyIzzEditField.ValueChangedFcn = createCallbackFcn(app, @Body2MomentofInertiaIxxIyyIzzEditFieldValueChanged, true);
            app.Body2MomentofInertiaIxxIyyIzzEditField.Placeholder = '[0, 0, 0]';
            app.Body2MassEditFieldLabel = uilabel(app.Body2Tab);
            app.Body2MassEditFieldLabel.Text = 'Body 2 Mass';
            app.Body2MassEditField = uieditfield(app.Body2Tab, 'numeric');
            app.Body2MassEditField.Limits = [0 Inf];
            app.Body2MassEditField.ValueChangedFcn = createCallbackFcn(app, @Body2MassEditFieldValueChanged, true);
            app.Body2GeometryFilenameEditFieldLabel = uilabel(app.Body2Tab);
            app.Body2GeometryFilenameEditFieldLabel.Text = 'Body 2 Geometry Filename';
            app.Body2GeometryFilenameEditField = uieditfield(app.Body2Tab, 'text');
            app.Body2GeometryFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @Body2GeometryFilenameEditFieldValueChanged, true);
            app.Body3Tab = uitab(app.TabGroup);
            app.Body3Tab.Title = 'Body 3';
            app.Body3MomentofInertiaIxxIyyIzzEditFieldLabel = uilabel(app.Body3Tab);
            app.Body3MomentofInertiaIxxIyyIzzEditFieldLabel.Text = 'Body 3 Moment of Inertia [Ixx, Iyy, Izz]';
            app.Body3MomentofInertiaIxxIyyIzzEditField = uieditfield(app.Body3Tab, 'text');
            app.Body3MomentofInertiaIxxIyyIzzEditField.ValueChangedFcn = createCallbackFcn(app, @Body3MomentofInertiaIxxIyyIzzEditFieldValueChanged, true);
            app.Body3MomentofInertiaIxxIyyIzzEditField.Placeholder = '[0, 0, 0]';
            app.Body3MassEditFieldLabel = uilabel(app.Body3Tab);
            app.Body3MassEditFieldLabel.Text = 'Body 3 Mass';
            app.Body3MassEditField = uieditfield(app.Body3Tab, 'numeric');
            app.Body3MassEditField.Limits = [0 Inf];
            app.Body3MassEditField.ValueChangedFcn = createCallbackFcn(app, @Body3MassEditFieldValueChanged, true);
            app.Body3GeometryFilenameEditFieldLabel = uilabel(app.Body3Tab);
            app.Body3GeometryFilenameEditFieldLabel.Text = 'Body 3 Geometry Filename';
            app.Body3GeometryFilenameEditField = uieditfield(app.Body3Tab, 'text');
            app.Body3GeometryFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @Body3GeometryFilenameEditFieldValueChanged, true);
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.PTO1Tab = uitab(app.TabGroup_2);
            app.PTO1Tab.Title = 'PTO 1';
            app.PTO1StiffnessEditFieldLabel = uilabel(app.PTO1Tab);
            app.PTO1StiffnessEditFieldLabel.Text = 'PTO 1 Stiffness';
            app.PTO1StiffnessEditField = uieditfield(app.PTO1Tab, 'numeric');
            app.PTO1StiffnessEditField.Limits = [0 Inf];
            app.PTO1StiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @PTO1StiffnessEditFieldValueChanged, true);
            app.PTO1LocationEditFieldLabel = uilabel(app.PTO1Tab);
            app.PTO1LocationEditFieldLabel.Text = 'PTO 1 Location';
            app.PTO1LocationEditField = uieditfield(app.PTO1Tab, 'text');
            app.PTO1LocationEditField.ValueChangedFcn = createCallbackFcn(app, @PTO1LocationEditFieldValueChanged, true);
            app.PTO1LocationEditField.Placeholder = '[0, 0, 0]';
            app.PTO1DampingEditFieldLabel = uilabel(app.PTO1Tab);
            app.PTO1DampingEditFieldLabel.Text = 'PTO 1 Damping';
            app.PTO1DampingEditField = uieditfield(app.PTO1Tab, 'numeric');
            app.PTO1DampingEditField.ValueChangedFcn = createCallbackFcn(app, @PTO1DampingEditFieldValueChanged, true);
            app.PTO2Tab = uitab(app.TabGroup_2);
            app.PTO2Tab.Title = 'PTO 2';
            app.PTO2StiffnessLabel = uilabel(app.PTO2Tab);
            app.PTO2StiffnessLabel.Text = 'PTO 2 Stiffness';
            app.PTO2StiffnessEditField = uieditfield(app.PTO2Tab, 'numeric');
            app.PTO2StiffnessEditField.Limits = [0 Inf];
            app.PTO2StiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @PTO2StiffnessEditFieldValueChanged, true);
            app.PTO2LocationLabel = uilabel(app.PTO2Tab);
            app.PTO2LocationLabel.Text = 'PTO 2 Location';
            app.PTO2LocationEditField = uieditfield(app.PTO2Tab, 'text');
            app.PTO2LocationEditField.ValueChangedFcn = createCallbackFcn(app, @PTO2LocationEditFieldValueChanged, true);
            app.PTO2LocationEditField.Placeholder = '[0, 0, 0]';
            app.PTO2DampingEditFieldLabel = uilabel(app.PTO2Tab);
            app.PTO2DampingEditFieldLabel.Text = 'PTO 2 Damping';
            app.PTO2DampingEditField = uieditfield(app.PTO2Tab, 'numeric');
            app.PTO2DampingEditField.ValueChangedFcn = createCallbackFcn(app, @PTO2DampingEditFieldValueChanged, true);
            app.PTO3Tab = uitab(app.TabGroup_2);
            app.PTO3Tab.Title = 'PTO 3';
            app.PTO3StiffnessLabel = uilabel(app.PTO3Tab);
            app.PTO3StiffnessLabel.Text = 'PTO 3 Stiffness';
            app.PTO3StiffnessEditField = uieditfield(app.PTO3Tab, 'numeric');
            app.PTO3StiffnessEditField.Limits = [0 Inf];
            app.PTO3StiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @PTO3StiffnessEditFieldValueChanged, true);
            app.PTO3LocationEditFieldLabel = uilabel(app.PTO3Tab);
            app.PTO3LocationEditFieldLabel.Text = 'PTO 3 Location';
            app.PTO3LocationEditField = uieditfield(app.PTO3Tab, 'text');
            app.PTO3LocationEditField.ValueChangedFcn = createCallbackFcn(app, @PTO3LocationEditFieldValueChanged, true);
            app.PTO3LocationEditField.Placeholder = '[0, 0, 0]';
            app.PTO3DampingEditFieldLabel = uilabel(app.PTO3Tab);
            app.PTO3DampingEditFieldLabel.Text = 'PTO 3 Damping';
            app.PTO3DampingEditField = uieditfield(app.PTO3Tab, 'numeric');
            app.PTO3DampingEditField.ValueChangedFcn = createCallbackFcn(app, @PTO3DampingEditFieldValueChanged, true);
            app.TabGroup_3 = uitabgroup(app.UIFigure);
            app.Const1Tab = uitab(app.TabGroup_3);
            app.Const1Tab.Title = 'Const. 1';
            app.Const1LocationEditFieldLabel = uilabel(app.Const1Tab);
            app.Const1LocationEditFieldLabel.Text = 'Const. 1 Location';
            app.Const1LocationEditField = uieditfield(app.Const1Tab, 'text');
            app.Const1LocationEditField.ValueChangedFcn = createCallbackFcn(app, @Const1LocationEditFieldValueChanged, true);
            app.Const1LocationEditField.Placeholder = '[0, 0, 0]';
            app.Const2Tab = uitab(app.TabGroup_3);
            app.Const2Tab.Title = 'Const. 2';
            app.Const2LocationEditFieldLabel = uilabel(app.Const2Tab);
            app.Const2LocationEditFieldLabel.Text = 'Const. 2 Location';
            app.Const2LocationEditField = uieditfield(app.Const2Tab, 'text');
            app.Const2LocationEditField.ValueChangedFcn = createCallbackFcn(app, @Const2LocationEditFieldValueChanged, true);
            app.Const2LocationEditField.Placeholder = '[0, 0, 0]';
            app.Const3Tab = uitab(app.TabGroup_3);
            app.Const3Tab.Title = 'Const. 3';
            app.Const3LocationEditFieldLabel = uilabel(app.Const3Tab);
            app.Const3LocationEditFieldLabel.Text = 'Const. 3 Location';
            app.Const3LocationEditField = uieditfield(app.Const3Tab, 'text');
            app.Const3LocationEditField.ValueChangedFcn = createCallbackFcn(app, @Const3LocationEditFieldValueChanged, true);
            app.Const3LocationEditField.Placeholder = '[0, 0, 0]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = write_wecsim_input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end